classdef Orientation_Preferences < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        alphaSliderLabel             matlab.ui.control.Label
        alphaSlider                  matlab.ui.control.Slider
        ThetaSliderLabel             matlab.ui.control.Label
        thetaSlider                  matlab.ui.control.Slider
        retina_RF_sigmaSliderLabel   matlab.ui.control.Label
        retina_RF_sigmaSlider        matlab.ui.control.Slider
        ctx_retina_sigmaSliderLabel  matlab.ui.control.Label
        ctx_retina_sigmaSlider       matlab.ui.control.Slider
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: alphaSlider
        function alphaSliderValueChanged(app, event)
            if isempty(app.thetaSlider.Value)
                theta = 0.15*pi;
            end
            if isempty(app.alphaSlider.Value)
                alpha = 0;
            end
            if isempty(app.ctx_retina_sigmaSlider.Value)
                ctx_retina_sigma = 56;
            end
            if isempty(app.retina_RF_sigmaSlider.Value)
                retina_RF_sigma = 7;
            end
            theta = app.thetaSlider.Value;
            alpha = app.alphaSlider.Value;
            ctx_retina_sigma = app.ctx_retina_sigmaSlider.Value;
            retina_RF_sigma = app.retina_RF_sigmaSlider.Value;

            %% Set up
            crop_x = 1600;
            crop_y = 1600;
            crop_ON = zeros(160,2); crop_OFF = zeros(160,2); %% toy data
            crop_ON(abs(crop_ON(:,1))>crop_x|abs(crop_ON(:,2))>crop_y,:) = [];
            crop_OFF(abs(crop_OFF(:,1))>crop_x|abs(crop_OFF(:,2))>crop_y,:) = [];
            pad_r = 1900;


            %% Retinal Ganglion Cell mosaics
            Result = rgcv1.RGC_mosaic(crop_ON,crop_OFF,pad_r,crop_x,crop_y,alpha, theta);
            pos_ON = cell2mat(Result(1));
            pos_OFF = cell2mat(Result(2));
            
            %% V1 Cortical Cell grids
            pos_xy = combvec(-1600:100:1600,-1600:100:1600)';
            
            %% Stimulus Gratings
            % [RF_xx, RF_yy] = meshgrid(linspace(0,pi,201));
            visual_space_to_simulate = 30;
            [RF_xx, RF_yy] = meshgrid(linspace(-visual_space_to_simulate,visual_space_to_simulate,201));
            
            retina_microns_per_degree = 1500 / 30;
            
            %% Calculate weights between stimulus/RF and cortical cells
            
            [RF_ctx_ON,RF_ctx_OFF, CTX_RF] = rgcv1.compute_RF(pos_ON,pos_OFF,pos_xy, ctx_retina_sigma, retina_RF_sigma, RF_xx, RF_yy, retina_microns_per_degree );
    
            %% Calculate orientation tuning of each cortical cell
            [best_response, best_response_location, selectivity, angles] = rgcv1.compute_OT(CTX_RF, RF_xx, RF_yy);
            a = reshape(angles, sqrt(numel(best_response_location)), sqrt(numel(best_response_location)));
    
            imagesc(app.UIAxes,a); 
            caxis(app.UIAxes,[0 2*pi]); 
            colorbar; colormap(app.UIAxes,hsv); axis xy image
            title(app.UIAxes,['c<-r: ' num2str(ctx_retina_sigma) '; r<-s: ' num2str(retina_RF_sigma)]);
            
            %% Plot selectivity of each cortical cell
            s = reshape(selectivity, sqrt(numel(angles)), sqrt(numel(angles)));
            imagesc(app.UIAxes2, s); caxis([0 1]); colorbar; colormap(app.UIAxes2,'default'); axis xy image
            title(app.UIAxes2,'Selectivity of each cortical cell');
            %% Plot orientation tuning of cortical cells whose selectivity >= threshold
            threshold = 0.5
            

            a=255*angles/(2*pi);
            U8 = uint8(a);
            %U8 = uint8(round(a - 1));
            a = ind2rgb(U8,colormap(app.UIAxes3,hsv));
            a(selectivity < threshold,:,1) = 0;
            a(selectivity < threshold,:,2) = 0;
            a(selectivity < threshold,:,3) = 0;
            
            a = reshape(a, sqrt(numel(angles)), sqrt(numel(angles)), 3);
            image(app.UIAxes3,a); caxis(app.UIAxes3,[0 255]); colorbar; colormap(app.UIAxes3,hsv);
            axis xy image
            title(app.UIAxes3,['Angle of each cortical cell whose selectivity >= ' num2str(threshold)]);


        end

        % Value changed function: thetaSlider
        function thetaSliderValueChanged(app, event)
            if isempty(app.thetaSlider.Value)
                theta = 0.15*pi;
            end
            if isempty(app.alphaSlider.Value)
                alpha = 0;
            end
            if isempty(app.ctx_retina_sigmaSlider.Value)
                ctx_retina_sigma = 56;
            end
            if isempty(app.retina_RF_sigmaSlider.Value)
                retina_RF_sigma = 7;
            end
            theta = app.thetaSlider.Value;
            alpha = app.alphaSlider.Value;
            ctx_retina_sigma = app.ctx_retina_sigmaSlider.Value;
            retina_RF_sigma = app.retina_RF_sigmaSlider.Value;

             %% Set up
            crop_x = 1600;
            crop_y = 1600;
            crop_ON = zeros(160,2); crop_OFF = zeros(160,2); %% toy data
            crop_ON(abs(crop_ON(:,1))>crop_x|abs(crop_ON(:,2))>crop_y,:) = [];
            crop_OFF(abs(crop_OFF(:,1))>crop_x|abs(crop_OFF(:,2))>crop_y,:) = [];
            pad_r = 1900;


            %% Retinal Ganglion Cell mosaics
            Result = rgcv1.RGC_mosaic(crop_ON,crop_OFF,pad_r,crop_x,crop_y,alpha, theta);
            pos_ON = cell2mat(Result(1));
            pos_OFF = cell2mat(Result(2));
            
            %% V1 Cortical Cell grids
            pos_xy = combvec(-1600:100:1600,-1600:100:1600)';
            
            %% Stimulus Gratings
            % [RF_xx, RF_yy] = meshgrid(linspace(0,pi,201));
            visual_space_to_simulate = 30;
            [RF_xx, RF_yy] = meshgrid(linspace(-visual_space_to_simulate,visual_space_to_simulate,201));
            
            retina_microns_per_degree = 1500 / 30;
            
            %% Calculate weights between stimulus/RF and cortical cells
            
            [RF_ctx_ON,RF_ctx_OFF, CTX_RF] = rgcv1.compute_RF(pos_ON,pos_OFF,pos_xy, ctx_retina_sigma, retina_RF_sigma, RF_xx, RF_yy, retina_microns_per_degree );
    
            %% Calculate orientation tuning of each cortical cell
            [best_response, best_response_location, selectivity, angles] = rgcv1.compute_OT(CTX_RF, RF_xx, RF_yy);
            a = reshape(angles, sqrt(numel(best_response_location)), sqrt(numel(best_response_location)));
    
            imagesc(app.UIAxes,a); 
            caxis(app.UIAxes,[0 2*pi]); 
            colorbar; colormap(app.UIAxes,hsv); axis xy image
            title(app.UIAxes,['c<-r: ' num2str(ctx_retina_sigma) '; r<-s: ' num2str(retina_RF_sigma)]);
            
             %% Plot selectivity of each cortical cell
            s = reshape(selectivity, sqrt(numel(angles)), sqrt(numel(angles)));
            imagesc(app.UIAxes2, s); caxis([0 1]); colorbar; colormap(app.UIAxes2,'default'); axis xy image
            title(app.UIAxes2,'Selectivity of each cortical cell');
            %% Plot orientation tuning of cortical cells whose selectivity >= threshold
            threshold = 0.5
            

            a=255*angles/(2*pi);
            U8 = uint8(a);
            %U8 = uint8(round(a - 1));
            a = ind2rgb(U8,colormap(app.UIAxes3,hsv));
            a(selectivity < threshold,:,1) = 0;
            a(selectivity < threshold,:,2) = 0;
            a(selectivity < threshold,:,3) = 0;
            
            a = reshape(a, sqrt(numel(angles)), sqrt(numel(angles)), 3);
            image(app.UIAxes3,a); caxis(app.UIAxes3,[0 255]); colorbar; colormap(app.UIAxes3,hsv);
            axis xy image
            title(app.UIAxes3,['Angle of each cortical cell whose selectivity >= ' num2str(threshold)]);

        end

        % Value changed function: retina_RF_sigmaSlider
        function retina_RF_sigmaSliderValueChanged(app, event)
            if isempty(app.thetaSlider.Value)
                theta = 0.15*pi;
            end
            if isempty(app.alphaSlider.Value)
                alpha = 0;
            end
            if isempty(app.ctx_retina_sigmaSlider.Value)
                ctx_retina_sigma = 56;
            end
            if isempty(app.retina_RF_sigmaSlider.Value)
                retina_RF_sigma = 7;
            end
            theta = app.thetaSlider.Value;
            alpha = app.alphaSlider.Value;
            ctx_retina_sigma = app.ctx_retina_sigmaSlider.Value;
            retina_RF_sigma = app.retina_RF_sigmaSlider.Value;
            
             %% Set up
            crop_x = 1600;
            crop_y = 1600;
            crop_ON = zeros(160,2); crop_OFF = zeros(160,2); %% toy data
            crop_ON(abs(crop_ON(:,1))>crop_x|abs(crop_ON(:,2))>crop_y,:) = [];
            crop_OFF(abs(crop_OFF(:,1))>crop_x|abs(crop_OFF(:,2))>crop_y,:) = [];
            pad_r = 1900;


            %% Retinal Ganglion Cell mosaics
            Result = rgcv1.RGC_mosaic(crop_ON,crop_OFF,pad_r,crop_x,crop_y,alpha, theta);
            pos_ON = cell2mat(Result(1));
            pos_OFF = cell2mat(Result(2));
            
            %% V1 Cortical Cell grids
            pos_xy = combvec(-1600:100:1600,-1600:100:1600)';
            
            %% Stimulus Gratings
            % [RF_xx, RF_yy] = meshgrid(linspace(0,pi,201));
            visual_space_to_simulate = 30;
            [RF_xx, RF_yy] = meshgrid(linspace(-visual_space_to_simulate,visual_space_to_simulate,201));
            
            retina_microns_per_degree = 1500 / 30;
            
            %% Calculate weights between stimulus/RF and cortical cells
            
            [RF_ctx_ON,RF_ctx_OFF, CTX_RF] = rgcv1.compute_RF(pos_ON,pos_OFF,pos_xy, ctx_retina_sigma, retina_RF_sigma, RF_xx, RF_yy, retina_microns_per_degree );
    
            %% Calculate orientation tuning of each cortical cell
            [best_response, best_response_location, selectivity, angles] = rgcv1.compute_OT(CTX_RF, RF_xx, RF_yy);
            a = reshape(angles, sqrt(numel(best_response_location)), sqrt(numel(best_response_location)));
    
            imagesc(app.UIAxes,a); 
            caxis(app.UIAxes,[0 2*pi]); 
            colorbar; colormap(app.UIAxes,hsv); axis xy image
            title(app.UIAxes,['c<-r: ' num2str(ctx_retina_sigma) '; r<-s: ' num2str(retina_RF_sigma)]);
            
             %% Plot selectivity of each cortical cell
            s = reshape(selectivity, sqrt(numel(angles)), sqrt(numel(angles)));
            imagesc(app.UIAxes2, s); caxis([0 1]); colorbar; colormap(app.UIAxes2,'default'); axis xy image
            title(app.UIAxes2,'Selectivity of each cortical cell');
            %% Plot orientation tuning of cortical cells whose selectivity >= threshold
            threshold = 0.5
            

            a=255*angles/(2*pi);
            U8 = uint8(a);
            %U8 = uint8(round(a - 1));
            a = ind2rgb(U8,colormap(app.UIAxes3,hsv));
            a(selectivity < threshold,:,1) = 0;
            a(selectivity < threshold,:,2) = 0;
            a(selectivity < threshold,:,3) = 0;
            disp(size(angles));
            disp(size(a));
            a = reshape(a, sqrt(numel(angles)), sqrt(numel(angles)), 3);
            image(app.UIAxes3,a); caxis(app.UIAxes3,[0 255]); colorbar; colormap(app.UIAxes3,hsv);
            axis xy image
            title(app.UIAxes3,['Angle of each cortical cell whose selectivity >= ' num2str(threshold)]);

        end

        % Value changed function: ctx_retina_sigmaSlider
        function ctx_retina_sigmaSliderValueChanged(app, event)
            if isempty(app.thetaSlider.Value)
                theta = 0.15*pi;
            end
            if isempty(app.alphaSlider.Value)
                alpha = 0;
            end
            if isempty(app.ctx_retina_sigmaSlider.Value)
                ctx_retina_sigma = 56;
            end
            if isempty(app.retina_RF_sigmaSlider.Value)
                retina_RF_sigma = 7;
            end
            theta = app.thetaSlider.Value;
            alpha = app.alphaSlider.Value;
            ctx_retina_sigma = app.ctx_retina_sigmaSlider.Value;
            retina_RF_sigma = app.retina_RF_sigmaSlider.Value;
            
             %% Set up
            crop_x = 1600;
            crop_y = 1600;
            crop_ON = zeros(160,2); crop_OFF = zeros(160,2); %% toy data
            crop_ON(abs(crop_ON(:,1))>crop_x|abs(crop_ON(:,2))>crop_y,:) = [];
            crop_OFF(abs(crop_OFF(:,1))>crop_x|abs(crop_OFF(:,2))>crop_y,:) = [];
            pad_r = 1900;


            %% Retinal Ganglion Cell mosaics
            Result = rgcv1.RGC_mosaic(crop_ON,crop_OFF,pad_r,crop_x,crop_y,alpha, theta);
            pos_ON = cell2mat(Result(1));
            pos_OFF = cell2mat(Result(2));
            
            %% V1 Cortical Cell grids
            pos_xy = combvec(-1600:100:1600,-1600:100:1600)';
            
            %% Stimulus Gratings
            % [RF_xx, RF_yy] = meshgrid(linspace(0,pi,201));
            visual_space_to_simulate = 30;
            [RF_xx, RF_yy] = meshgrid(linspace(-visual_space_to_simulate,visual_space_to_simulate,201));
            
            retina_microns_per_degree = 1500 / 30;
            
            %% Calculate weights between stimulus/RF and cortical cells
            
            [RF_ctx_ON,RF_ctx_OFF, CTX_RF] = rgcv1.compute_RF(pos_ON,pos_OFF,pos_xy, ctx_retina_sigma, retina_RF_sigma, RF_xx, RF_yy, retina_microns_per_degree );
    
            %% Calculate orientation tuning of each cortical cell
            [best_response, best_response_location, selectivity, angles] = rgcv1.compute_OT(CTX_RF, RF_xx, RF_yy);
            a = reshape(angles, sqrt(numel(best_response_location)), sqrt(numel(best_response_location)));
    
            imagesc(app.UIAxes,a); 
            caxis(app.UIAxes,[0 2*pi]); 
            colorbar; colormap(app.UIAxes,hsv); axis xy image
            title(app.UIAxes,['c<-r: ' num2str(ctx_retina_sigma) '; r<-s: ' num2str(retina_RF_sigma)]);
            
             %% Plot selectivity of each cortical cell
            s = reshape(selectivity, sqrt(numel(angles)), sqrt(numel(angles)));
            imagesc(app.UIAxes2, s); caxis([0 1]); colorbar; colormap(app.UIAxes2,'default'); axis xy image
            title(app.UIAxes2,'Selectivity of each cortical cell');
            %% Plot orientation tuning of cortical cells whose selectivity >= threshold
            threshold = 0.5
            

            a=255*angles/(2*pi);
            U8 = uint8(a);
            %U8 = uint8(round(a - 1));
            a = ind2rgb(U8,colormap(app.UIAxes3,hsv));
            a(selectivity < threshold,:,1) = 0;
            a(selectivity < threshold,:,2) = 0;
            a(selectivity < threshold,:,3) = 0;
            
            a = reshape(a, sqrt(numel(angles)), sqrt(numel(angles)), 3);
            image(app.UIAxes3,a); caxis(app.UIAxes3,[0 255]); colorbar; colormap(app.UIAxes3,hsv);
            axis xy image
            title(app.UIAxes3,['Angle of each cortical cell whose selectivity >= ' num2str(threshold)]);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'All Angles')
            xlabel(app.UIAxes, 'Selectivity')
            ylabel(app.UIAxes, 'Y')
            app.alphaSliderLabel = uilabel(app.UIFigure);
            app.alphaSliderLabel.Text = 'alpha';
            app.alphaSlider = uislider(app.UIFigure);
            app.alphaSlider.Limits = [0 0.5];
            app.alphaSlider.ValueChangedFcn = createCallbackFcn(app, @alphaSliderValueChanged, true);
            app.ThetaSliderLabel = uilabel(app.UIFigure);
            app.ThetaSliderLabel.Text = 'Theta';
            app.thetaSlider = uislider(app.UIFigure);
            app.thetaSlider.Limits = [0 3.14];
            app.thetaSlider.ValueChangedFcn = createCallbackFcn(app, @thetaSliderValueChanged, true);
            app.retina_RF_sigmaSliderLabel = uilabel(app.UIFigure);
            app.retina_RF_sigmaSliderLabel.Text = 'retina_RF_sigma';
            app.retina_RF_sigmaSlider = uislider(app.UIFigure);
            app.retina_RF_sigmaSlider.Limits = [0 150];
            app.retina_RF_sigmaSlider.ValueChangedFcn = createCallbackFcn(app, @retina_RF_sigmaSliderValueChanged, true);
            app.ctx_retina_sigmaSliderLabel = uilabel(app.UIFigure);
            app.ctx_retina_sigmaSliderLabel.Text = 'ctx_retina_sigma';
            app.ctx_retina_sigmaSlider = uislider(app.UIFigure);
            app.ctx_retina_sigmaSlider.Limits = [0 150];
            app.ctx_retina_sigmaSlider.ValueChangedFcn = createCallbackFcn(app, @ctx_retina_sigmaSliderValueChanged, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Selectivity')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Angles w/ High Selectivity')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Orientation_Preferences

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end