classdef Scaling_Factor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        UIAxes            matlab.ui.control.UIAxes
        AlphaSliderLabel  matlab.ui.control.Label
        AlphaSlider       matlab.ui.control.Slider
        ThetaSliderLabel  matlab.ui.control.Label
        ThetaSlider       matlab.ui.control.Slider
        UIAxes2           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AlphaSlider
        function AlphaSliderValueChanged(app, event)
            value = app.AlphaSlider.Value;
            S = [];
            for i = 1:100
                S(end+1) = rgcv1.scalefactor(value,8*pi/100*i);
            end
            title(app.UIAxes, 'Scaling Factor');
            plot(app.UIAxes, linspace(8*pi/100,8*pi,100), S);
            ylim(app.UIAxes,[0 10]); xlim(app.UIAxes,[0 20])
            
             theta = app.ThetaSlider.Value;
            alpha = app.AlphaSlider.Value;
            
            crop_x = 1600;
            crop_y = 1600;
            crop_ON = zeros(160,2); crop_OFF = zeros(160,2); %% toy data
            crop_ON(abs(crop_ON(:,1))>crop_x|abs(crop_ON(:,2))>crop_y,:) = [];
            crop_OFF(abs(crop_OFF(:,1))>crop_x|abs(crop_OFF(:,2))>crop_y,:) = [];
            pad_r = 1900;
       

            Result = rgcv1.RGC_mosaic(crop_ON,crop_OFF,pad_r,crop_x,crop_y, alpha, theta);
            pos_ON = cell2mat(Result(1));
            pos_OFF = cell2mat(Result(2));
            
            pos_xy = combvec(-1600:100:1600,-1600:100:1600)';
            
            [RF_xx, RF_yy] = meshgrid(linspace(0,pi,201));
            
            ctx_retina_sigma = 56;
            retina_RF_sigma = 7;
            
            [RF_ctx_ON,RF_ctx_OFF, CTX_RF] = rgcv1.compute_RF(pos_ON,pos_OFF,pos_xy, ctx_retina_sigma, retina_RF_sigma, RF_xx, RF_yy);
            [best_response, best_response_location, selectivity, angles] = rgcv1.compute_OT(CTX_RF, RF_xx, RF_yy);
            a = reshape(angles, sqrt(numel(angles)), sqrt(numel(angles)));
            
            threshold = 0.8;
            
            a=255*angles/(2*pi);
            U8 = uint8(round(a - 1));
            a = ind2rgb(U8,colormap(app.UIAxes2, hsv));
            a(selectivity < threshold,:,1) = 0;
            a(selectivity < threshold,:,2) = 0;
            a(selectivity < threshold,:,3) = 0;
            
            a = reshape(a, sqrt(numel(angles)), sqrt(numel(angles)), 3);
            image(app.UIAxes2, a); caxis(app.UIAxes2,[0 255]); colorbar(app.UIAxes2); colormap(app.UIAxes2, hsv);


        end

        % Value changed function: ThetaSlider
        function ThetaSliderValueChanged(app, event)
            theta = app.ThetaSlider.Value;
            alpha = app.AlphaSlider.Value;
            
            crop_x = 1600;
            crop_y = 1600;
            crop_ON = zeros(160,2); crop_OFF = zeros(160,2); %% toy data
            crop_ON(abs(crop_ON(:,1))>crop_x|abs(crop_ON(:,2))>crop_y,:) = [];
            crop_OFF(abs(crop_OFF(:,1))>crop_x|abs(crop_OFF(:,2))>crop_y,:) = [];
            pad_r = 1900;
       

            Result = rgcv1.RGC_mosaic(crop_ON,crop_OFF,pad_r,crop_x,crop_y, alpha, theta);
            pos_ON = cell2mat(Result(1));
            pos_OFF = cell2mat(Result(2));
            
            pos_xy = combvec(-1600:100:1600,-1600:100:1600)';
            
            [RF_xx, RF_yy] = meshgrid(linspace(0,pi,201));
            
            ctx_retina_sigma = 56;
            retina_RF_sigma = 7;
            
            [RF_ctx_ON,RF_ctx_OFF, CTX_RF] = rgcv1.compute_RF(pos_ON,pos_OFF,pos_xy, ctx_retina_sigma, retina_RF_sigma, RF_xx, RF_yy);
            [best_response, best_response_location, selectivity, angles] = rgcv1.compute_OT(CTX_RF, RF_xx, RF_yy);
            a = reshape(angles, sqrt(numel(angles)), sqrt(numel(angles)));
            
            threshold = 0.8;
            
            a=255*angles/(2*pi);
            U8 = uint8(round(a - 1));
            a = ind2rgb(U8,colormap(app.UIAxes2, hsv));
            a(selectivity < threshold,:,1) = 0;
            a(selectivity < threshold,:,2) = 0;
            a(selectivity < threshold,:,3) = 0;
            
            a = reshape(a, sqrt(numel(angles)), sqrt(numel(angles)), 3);
            image(app.UIAxes2, a); caxis(app.UIAxes2,[0 255]); colorbar(app.UIAxes2); colormap(app.UIAxes2, hsv);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Scaling Factor')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.AlphaSliderLabel = uilabel(app.UIFigure);
            app.AlphaSliderLabel.Text = 'Alpha';
            app.AlphaSlider = uislider(app.UIFigure);
            app.AlphaSlider.Limits = [0 1];
            app.AlphaSlider.ValueChangedFcn = createCallbackFcn(app, @AlphaSliderValueChanged, true);
            app.ThetaSliderLabel = uilabel(app.UIFigure);
            app.ThetaSliderLabel.Text = 'Theta';
            app.ThetaSlider = uislider(app.UIFigure);
            app.ThetaSlider.Limits = [0 3.14];
            app.ThetaSlider.ValueChangedFcn = createCallbackFcn(app, @ThetaSliderValueChanged, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Angles with selectivity >= threshold')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Scaling_Factor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end