classdef Sigma < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        alphaSliderLabel             matlab.ui.control.Label
        alphaSlider                  matlab.ui.control.Slider
        ThetaSliderLabel             matlab.ui.control.Label
        thetaSlider                  matlab.ui.control.Slider
        retina_RF_sigmaSliderLabel   matlab.ui.control.Label
        retina_RF_sigmaSlider        matlab.ui.control.Slider
        ctx_retina_sigmaSliderLabel  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes4                      matlab.ui.control.UIAxes
        ctx_retina_sigmaSlider       matlab.ui.control.Slider
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: alphaSlider
        function alphaSliderValueChanged(app, event)

            if isempty(app.thetaSlider.Value)
                theta = 0.15*pi;
            end
            if isempty(app.alphaSlider.Value)
                alpha = 0;
            end
            if isempty(app.ctx_retina_sigmaSlider.Value)
                ctx_retina_sigma = 56;
            end
            if isempty(app.retina_RF_sigmaSlider.Value)
                retina_RF_sigma = 7;
            end
            theta = app.thetaSlider.Value;
            alpha = app.alphaSlider.Value;
            ctx_retina_sigma = app.ctx_retina_sigmaSlider.Value;
            retina_RF_sigma = app.retina_RF_sigmaSlider.Value;
        
            rgcv1.code_for_OP_app(theta, alpha, ctx_retina_sigma, retina_RF_sigma);

            rgcv1.plot_retina_cortex();

        end

        % Value changed function: thetaSlider
        function thetaSliderValueChanged(app, event)

            if isempty(app.thetaSlider.Value)
                theta = 0.15*pi;
            end
            if isempty(app.alphaSlider.Value)
                alpha = 0;
            end
            if isempty(app.ctx_retina_sigmaSlider.Value)
                ctx_retina_sigma = 56;
            end
            if isempty(app.retina_RF_sigmaSlider.Value)
                retina_RF_sigma = 7;
            end
            theta = app.thetaSlider.Value;
            alpha = app.alphaSlider.Value;
            ctx_retina_sigma = app.ctx_retina_sigmaSlider.Value;
            retina_RF_sigma = app.retina_RF_sigmaSlider.Value;
        
            rgcv1.code_for_OP_app(theta, alpha, ctx_retina_sigma, retina_RF_sigma);

            rgcv1.plot_retina_cortex();
        end

        % Value changed function: retina_RF_sigmaSlider
        function retina_RF_sigmaSliderValueChanged(app, event)

            if isempty(app.thetaSlider.Value)
                theta = 0.15*pi;
            end
            if isempty(app.alphaSlider.Value)
                alpha = 0;
            end
            if isempty(app.ctx_retina_sigmaSlider.Value)
                ctx_retina_sigma = 56;
            end
            if isempty(app.retina_RF_sigmaSlider.Value)
                retina_RF_sigma = 7;
            end
            theta = app.thetaSlider.Value;
            alpha = app.alphaSlider.Value;
            ctx_retina_sigma = app.ctx_retina_sigmaSlider.Value;
            retina_RF_sigma = app.retina_RF_sigmaSlider.Value;
        
            rgcv1.code_for_OP_app(theta, alpha, ctx_retina_sigma, retina_RF_sigma);

            rgcv1.plot_retina_cortex();
        end

        % Value changed function: ctx_retina_sigmaSlider
        function ctx_retina_sigmaSliderValueChanged(app, event)

            if isempty(app.thetaSlider.Value)
                theta = 0.15*pi;
            end
            if isempty(app.alphaSlider.Value)
                alpha = 0;
            end
            if isempty(app.ctx_retina_sigmaSlider.Value)
                ctx_retina_sigma = 56;
            end
            if isempty(app.retina_RF_sigmaSlider.Value)
                retina_RF_sigma = 7;
            end
            theta = app.thetaSlider.Value;
            alpha = app.alphaSlider.Value;
            ctx_retina_sigma = app.ctx_retina_sigmaSlider.Value;
            retina_RF_sigma = app.retina_RF_sigmaSlider.Value;
        
            rgcv1.code_for_OP_app(theta, alpha, ctx_retina_sigma, retina_RF_sigma);

            rgcv1.plot_retina_cortex();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.alphaSliderLabel = uilabel(app.UIFigure);
            app.alphaSliderLabel.Text = 'alpha';
            app.alphaSlider = uislider(app.UIFigure);
            app.alphaSlider.Limits = [0 0.5];
            app.alphaSlider.ValueChangedFcn = createCallbackFcn(app, @alphaSliderValueChanged, true);
            app.ThetaSliderLabel = uilabel(app.UIFigure);
            app.ThetaSliderLabel.Text = 'Theta';
            app.thetaSlider = uislider(app.UIFigure);
            app.thetaSlider.Limits = [0 3.14];
            app.thetaSlider.ValueChangedFcn = createCallbackFcn(app, @thetaSliderValueChanged, true);
            app.retina_RF_sigmaSliderLabel = uilabel(app.UIFigure);
            app.retina_RF_sigmaSliderLabel.Text = 'retina_RF_sigma';
            app.retina_RF_sigmaSlider = uislider(app.UIFigure);
            app.retina_RF_sigmaSlider.Limits = [0 150];
            app.retina_RF_sigmaSlider.ValueChangedFcn = createCallbackFcn(app, @retina_RF_sigmaSliderValueChanged, true);
            app.ctx_retina_sigmaSliderLabel = uilabel(app.UIFigure);
            app.ctx_retina_sigmaSliderLabel.Text = 'ctx_retina_sigma';
            app.ctx_retina_sigmaSlider = uislider(app.UIFigure);
            app.ctx_retina_sigmaSlider.Limits = [0 150];
            app.ctx_retina_sigmaSlider.ValueChangedFcn = createCallbackFcn(app, @ctx_retina_sigmaSliderValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sigma

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end